classdef gui_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AlphaEditField                 matlab.ui.control.NumericEditField
        AlphaEditFieldLabel            matlab.ui.control.Label
        StddevofnewLpsEditField        matlab.ui.control.NumericEditField
        StddevofnewLpsEditFieldLabel   matlab.ui.control.Label
        NoofnewLpsEditField            matlab.ui.control.NumericEditField
        NoofnewLpsEditFieldLabel       matlab.ui.control.Label
        PixelofInterestPOILabel        matlab.ui.control.Label
        LightpositionsuploadedLPfileLabel  matlab.ui.control.Label
        SelectLPfileEditField          matlab.ui.control.EditField
        SelectLPfileEditFieldLabel     matlab.ui.control.Label
        BrowseButton_3                 matlab.ui.control.Button
        RTIdataTextArea                matlab.ui.control.TextArea
        RTIdataTextAreaLabel           matlab.ui.control.Label
        SelectRTIFolderEditField       matlab.ui.control.EditField
        SelectRTIFolderEditFieldLabel  matlab.ui.control.Label
        BrowseButton_2                 matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        SelectTestImageEditField       matlab.ui.control.EditField
        SelectTestImageEditFieldLabel  matlab.ui.control.Label
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mask = []; % Description
    end
    
    properties (Access = private)
        %lpfile = []; % Description
        %dome_radius = [];
        %light_positions = [];
        lps = [];
        az =[];
        el = [];
    end
    
    methods (Access = private)
        
        function [light_positions,az_coords,el_coords] = readlpfile(app,file_path,dome_radius)
           % function [light_positions,az_coords,el_coords] = read_lp_file(file_path, dome_radius)
            light_positions = [];
        
            % Check if the file exists
            if ~isfile(file_path)
                return;
            end
        
            % Open the file and handle errors
            try
                fileID = fopen(file_path, 'r');
            catch ME
                fprintf('Caught error: %s\n', getReport(ME));
                app.light_positions = {'ERROR'};
                return;
            end
        
            % Read the file data
            data = textscan(fileID, '%s%f%f%f');
            fclose(fileID);
            
            % Get the number of lights
            numLights = str2double(data{1}{1});
        
            x_coords = zeros(numLights, 1);
            y_coords = zeros(numLights, 1);
            z_coords = zeros(numLights, 1);
        
            az_coords = zeros(numLights, 1);
            el_coords = zeros(numLights, 1);
            
            
            for idx = 2 : numLights + 1
                x = data{2}(idx);
                y = data{3}(idx);
                z = data{4}(idx);
                [r, az, el] = cart2sph(app,x,y,z);
                r = dome_radius;
                [x, y, z] = sph2cart(app,az, el, r);
                x_coords(idx-1) = x;
                y_coords(idx-1) = y;
                z_coords(idx-1) = z;
                az_coords(idx-1) = az;
                el_coords(idx-1) = el;
            end
             
            light_positions = [x_coords, y_coords, z_coords];
            
        %     % Create 3D scatter plot
        %     scatter3(x_coords, y_coords, z_coords, 'filled');
        %     xlabel('X')
        %     ylabel('Y')
        %     zlabel('Z')
        %     grid off
        end


        function [x, y, z] = sph2cart(app,az, el, r)
            rcos_theta = r * cos(el);
            x = rcos_theta * cos(az);
            y = rcos_theta * sin(az);
            z = r * sin(el);
        end


        function [r, az, el] = cart2sph(app,x, y, z)
            if nargin < 3, z = 1.0; end
            fprintf('%f, %f, %f\n', x, y, z);
            hxy = hypot(x, y);
            r = hypot(hxy, z);
            el = atan2(z, hxy);
            az = atan2(y, x);
        end
        
        function plot_points(app,points, new_figure)
            if nargin < 2 || new_figure
            
            else
                hold(app.UIAxes7, "on");
            end
            
            scatter3(app.UIAxes7, points(:,1), points(:,2), points(:,3), 150, '.');
            xlabel('X');
            ylabel('Y');
            zlabel('Z');
        end

        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %[file,path] = uigetfile('E:\gracqrti\data\Experiments\Ex3\it1\acq',{'*.jpg';'*.bnp';'*.gif';'*.tiff'}, 'Select a file');
            [file,path] = uigetfile('E:\gracqrti\data\Experiments\Ex3\it1\acq','Select a file');
            app.SelectTestImageEditField.Value = path;
            img = imread(fullfile(path,file));
            imshow(img, 'Parent', app.UIAxes);
            h = imrect(app.UIAxes);
            % Get the position of the rectangle
            roiPosition = wait(h);
            %disp(['Rectangle position: ', num2str(roiPosition)]);
            %testimage_path = path;
            app.mask = false(size(img, 1), size(img, 2));
            app.mask(round(roiPosition(2)):round(roiPosition(2)+roiPosition(4)), round(roiPosition(1)):round(roiPosition(1)+roiPosition(3))) = true;
            imshow(app.mask,'Parent',app.UIAxes2)
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            folder = uigetdir('E:\gracqrti\data\Experiments\Ex3\it1\acq');
            app.SelectRTIFolderEditField.Value = folder;
            filePattern = fullfile(folder, '*.png');  % Just to detect images in the folder.

            theFiles = dir(filePattern); % lists files and folders in the current folder

            if isempty(theFiles) % error handling
                errordlg('No PNG images found in the selected directory.');
                return;
            end


            % Extracting filenames and concatenating them into a single string
            fileNames = {theFiles.name};
            fileNamesStr = strjoin(fileNames, '\n ');


            mlic = {}; % Initialize cell array for reading all the RTI images 
            
            for k = 1 : length(theFiles)
                baseFileName = theFiles(k).name; %theFiles is a structure array whose field 'name'(e.g. 0001.png) is accessed here
                fullFileName = fullfile(folder, baseFileName); % append the path
                fprintf(1, 'Now reading %s\n', fullFileName); % just to tell that its reading image, everything okay till now.
                img_clr = imread(fullFileName); % read image
                %img = im2gray(img_clr); % convert to grayscale if not
                if ~isempty(img_clr)
                    mlic{end+1} = img_clr; % if the variable img is not empty append the cell array mlic 
                end
            end
                        
            app.RTIdataTextArea.Value = fileNamesStr;
            
             % Determine total height needed for all images (assuming they all have the same width and height for simplicity)
            exampleImage = imread(fullfile(folder, theFiles(1).name));
%             figure; imshow(exampleImage); % error handling
            [imgHeight, imgWidth, ~] = size(exampleImage);
            totalHeight = imgHeight * length(theFiles);

            % Create uipanel and uiscrollbar
            scrollPanel = uipanel(app.UIFigure, 'Position', [0.1 0.1 0.5 0.5], 'Units', 'normalized','Visible', 'on');
            scrollBar = uislider(app.UIFigure); 
            scrollBar.Position = [0.6 0.1 0.03 0.5];  % adjust the position as needed
            scrollBar.Limits = [1 totalHeight - 0.5*scrollPanel.Position(4)];
            scrollBar.Value = 1;
            addlistener(scrollBar, 'ValueChanged', @(src, event) updatePanelPosition(src, scrollPanel, imgHeight));

            imgPanel = uipanel('Parent', scrollPanel, 'Units', 'pixels', 'Position', [0, 0, imgWidth, totalHeight], ...
                               'BorderType', 'none');

                % Load and display images
            for i = 1:length(theFiles)
                imgPath = fullfile(folder, theFiles(i).name);
                img = imread(imgPath);
                uiaxes('Parent', imgPanel, 'Units', 'pixels', 'Position', [0 totalHeight - i*imgHeight imgWidth imgHeight]);
                imshow(img);
            end

            function updatePanelPosition(slider, panel, imgHeight)
                    sliderVal = slider.Value;
                    panelPos = panel.Position;
                    panelPos(2) = -sliderVal + imgHeight;
                    panel.Position = panelPos;
            end


            [row, col] = find(app.mask);
            
            % Initialize a cell array to store ROI signals
            ROI_signals = cell(1, length(row));
            for idx = 1:length(row)
                % Get the current pixel location
                currRow = row(idx);
                currCol = col(idx);
                
                % Initialize an array to store the pixel intensities
                pixelIntensities = zeros(1, length(mlic));
                
                for i = 1 : length(mlic)
                    pixelIntensities(i) = mlic{i}(currRow, currCol);  % MATLAB indexing is 1-based not 0-based
                end
                
                % Store the pixel intensities in the ROI_signals cell array
                ROI_signals{idx} = pixelIntensities;
            end
            % Read the signal in RTI stack of images
            
            % Calculate the standard deviation of each signal in ROI_signals
            stdDevs = cellfun(@std, ROI_signals);
            
            % Find the index of the signal with the highest standard deviation
            [~, maxStdIdx] = max(stdDevs);
            
            % Get the pixel location corresponding to the signal with the highest standard deviation
            maxStdPixelRow = row(maxStdIdx);
            maxStdPixelCol = col(maxStdIdx);

            % Print the pixel location
            fprintf('The pixel location with the highest standard deviation is at row %d and column %d.\n', maxStdPixelRow, maxStdPixelCol);

            % Calculate the gradient of the signal (POI)
            x = double(ROI_signals{maxStdIdx});
            gradSignal = gradient(x);
            plot(app.UIAxes4,1:length(x), x, 'LineWidth', 2)
            plot(app.UIAxes5,1:length(gradSignal), gradSignal, 'LineWidth', 2)

            for idx = 1:length(ROI_signals)
                % Get the current signal
                signal = ROI_signals{idx};
                
                % Calculate the gradient of the signal
                gradSignal = gradient(double(signal));
                
                % Plot the signal with different colors for each signal
                plot(app.UIAxes6, signal, 'DisplayName', ['Signal ', num2str(idx), ' (Std Dev = ', num2str(std(signal)), ')']);
                hold(app.UIAxes6,'on');
            end
            hold(app.UIAxes6,'off');


            %% New Lps

            gr = abs(gradient(gradSignal));
            [max_x, maxIndex_x] = max(gr);
            if (maxIndex_x+1) > numel(app.az)
                xr = max_x - gr(1);
            else 
                xr = max_x - gr(maxIndex_x+1);
            end
            
            if (maxIndex_x-1) == 0
                xl = max_x - gr(end);
            else
                xl = max_x - gr(maxIndex_x-1);
            end
            

            if (maxIndex_x-1) == 0
                %[max_x, idx_max_x] = max(x);
                left_az = (app.az(maxIndex_x) + app.az(end))/2;
            else
                left_az = (app.az(maxIndex_x) + app.az(maxIndex_x - 1))/2;
            end
            
            if (maxIndex_x+1) > numel(app.az)
                right_az = (app.az(maxIndex_x) + ap.az(1))/2;
            else
                right_az = (app.az(maxIndex_x) + app.az(maxIndex_x + 1))/2;
            end


            st = app.StddevofnewLpsEditField.Value 
            
            if abs(xl)>abs(xr)
                points = generate_gaussian_ring_points_c(3,left_az,app.el(1),st);
                plot_points(points);
                plot_points(app.lps,false);
                fprintf('Choosing Left')
            else
                points = generate_gaussian_ring_points_c(3,right_az,app.el(1),st);
                plot_points(points);
                plot_points(app.lps,false);
                fprintf('Choosing Right')
            end

        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            [lpfile,lpfilepath] = uigetfile('E:\gracqrti\data\Experiments\Ex3\it1','Select lp file');
            app.SelectLPfileEditField.Value = lpfile;
            lpfilepath = fullfile(lpfilepath,lpfile);
            [app.lps,app.az,app.el] = readlpfile(app,lpfilepath,1);

            scatter3(app.UIAxes3,app.lps(:,1),app.lps(:,2),app.lps(:,3),'filled');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Gradient')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'ROI Signals')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'New Lps')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.SelectTestImageEditFieldLabel = uilabel(app.UIFigure);
            app.SelectTestImageEditFieldLabel.Text = 'Select Test Image';
            app.SelectTestImageEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.SelectRTIFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SelectRTIFolderEditFieldLabel.Text = 'Select RTI Folder';
            app.SelectRTIFolderEditField = uieditfield(app.UIFigure, 'text');
            app.RTIdataTextAreaLabel = uilabel(app.UIFigure);
            app.RTIdataTextAreaLabel.Text = 'RTI data';
            app.RTIdataTextArea = uitextarea(app.UIFigure);
            app.BrowseButton_3 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.SelectLPfileEditFieldLabel = uilabel(app.UIFigure);
            app.SelectLPfileEditFieldLabel.Text = 'Select LP file';
            app.SelectLPfileEditField = uieditfield(app.UIFigure, 'text');
            app.LightpositionsuploadedLPfileLabel = uilabel(app.UIFigure);
            app.LightpositionsuploadedLPfileLabel.Text = 'Light positions (uploaded LP file)';
            app.PixelofInterestPOILabel = uilabel(app.UIFigure);
            app.PixelofInterestPOILabel.Text = 'Pixel of Interest (POI)';
            app.NoofnewLpsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofnewLpsEditFieldLabel.Text = 'No of new Lp''s';
            app.NoofnewLpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StddevofnewLpsEditFieldLabel = uilabel(app.UIFigure);
            app.StddevofnewLpsEditFieldLabel.Text = 'Std dev of new Lp''s';
            app.StddevofnewLpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end